classdef antenna < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AutoImageGenerationCheckBox     matlab.ui.control.CheckBox
        ShowConnectionCheckBox_2        matlab.ui.control.CheckBox
        AntennaDiagramsHUBButtonGroup   matlab.ui.container.ButtonGroup
        AzimuthButton                   matlab.ui.control.ToggleButton
        ElevationButton                 matlab.ui.control.ToggleButton
        DButton                         matlab.ui.control.ToggleButton
        ModellButton_2                  matlab.ui.control.ToggleButton
        AntennaDiagramsX1ButtonGroup    matlab.ui.container.ButtonGroup
        AzimuthButton_2                 matlab.ui.control.ToggleButton
        ElevationButton_2               matlab.ui.control.ToggleButton
        DButton_2                       matlab.ui.control.ToggleButton
        ModellButton                    matlab.ui.control.ToggleButton
        ConnectedLampLabel              matlab.ui.control.Label
        ConnectedLamp                   matlab.ui.control.Lamp
        LastConnectedLatitudeEditFieldLabel  matlab.ui.control.Label
        LastConnectedLatitudeEditField  matlab.ui.control.EditField
        EquivalentLatChangeLabel        matlab.ui.control.Label
        EquivalentLatChangeEditField    matlab.ui.control.NumericEditField
        EquivalentLonChangeLabel        matlab.ui.control.Label
        EquivalentLonChangeEditField    matlab.ui.control.NumericEditField
        LastConnectedDistanceEditFieldLabel  matlab.ui.control.Label
        LastConnectedDistanceEditField  matlab.ui.control.EditField
        LastConnectedLongitudeEditFieldLabel  matlab.ui.control.Label
        LastConnectedLongitudeEditField  matlab.ui.control.EditField
        DisconnectedLampLabel           matlab.ui.control.Label
        DisconnectedLamp                matlab.ui.control.Lamp
        TransmitterWSNLatitudeLabel     matlab.ui.control.Label
        TransmitterWSNLatitudeEditField  matlab.ui.control.NumericEditField
        TransmitterWSNLongitudeLabel    matlab.ui.control.Label
        TransmitterWSNLongitudeEditField  matlab.ui.control.NumericEditField
        RecieverHUBLatitudeEditFieldLabel  matlab.ui.control.Label
        RecieverHUBLatitudeEditField    matlab.ui.control.NumericEditField
        RecieverHUBLongitudeEditFieldLabel  matlab.ui.control.Label
        RecieverHUBLongitudeEditField   matlab.ui.control.NumericEditField
        MoveWSNfromdefaultinYDirectionby1mSpinner_2Label  matlab.ui.control.Label
        MoveWSNfromdefaultinYDirectionby1mSpinner_2  matlab.ui.control.Spinner
        MoveWSNfromdefaultinXDirectionby1mSpinnerLabel  matlab.ui.control.Label
        MoveWSNfromdefaultinXDirectionby1mSpinner  matlab.ui.control.Spinner
        SignalStrengthEditFieldLabel    matlab.ui.control.Label
        SignalStrengthEditField         matlab.ui.control.EditField
        ShowCoverageCheckBox            matlab.ui.control.CheckBox
        USAntennaCheckBox               matlab.ui.control.CheckBox
        EUAntennaCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
       
    end
    
    properties (Access = public)
        Latitude;  % Description
        transmitterName = "Tempelhofer Feld tx";
        recieverName = "Tempelhofer Feld rx ";
        transmitterLat = 52.474747;
        transmitterLon = 13.397228;
        recieverLat = 52.474819;
        recieverLon = 13.399026;
        Hub= dipole('Length',0.2794 ,'Width',0.039285, 'FeedOffset',-0.1396);
        Wsn= dipole('Length',0.05 ,'Width',0.001, 'FeedOffset',-0.024);
        fq;
        dInMeters=1;
        showCoverage;
        latChange;
        lonChange;
        signalStrength;
        lastconnectedlat;
        lastconnectedlon;
        lastconnecteddistance;
        transmissionPower;
        
    end
    
    methods (Access = public)
        
       
end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AutoImageGenerationCheckBox
        function AutoImageGenerationCheckBoxValueChanged(app, event)
            value = app.AutoImageGenerationCheckBox.Value;
                    show(app.Hub);
                    print(gcf, 'Hub_Antenne.png','-dpng', '-r150');
                    pattern(app.Hub, app.fq);
                    print(gcf, 'Hub_3d_Pattern.png','-dpng', '-r150');
                    patternAzimuth(app.Hub, app.fq);
                    print(gcf, 'Hub_Azimuth_pattern.png','-dpng', '-r150');
                    patternElevation(app.Hub, app.fq);
                    print(gcf, 'Hub_Elevation_pattern.png','-dpng', '-r150');
                    impedance(app.Hub, linspace(850e6, 890e6, 51));
                    print(gcf, 'Hub_Impedance.png','-dpng', '-r150');
                    show(app.Wsn);
                    print(gcf, 'Wsn_Antenne.png','-dpng', '-r150');
                    pattern(app.Wsn, app.fq);
                    print(gcf, 'Wsn_3d_Pattern.png','-dpng', '-r150');
                    patternAzimuth(app.Wsn, app.fq);
                    print(gcf, 'Wsn_Azimuth_pattern.png','-dpng', '-r150');
                    patternElevation(app.Wsn, app.fq);
                    print(gcf, 'Wsn_Elevation_pattern.png','-dpng', '-r150');
                    impedance(app.Wsn, linspace(850e6, 890e6, 51));
                    print(gcf, 'Wsn_Impedance.png','-dpng', '-r150');
            
        end

        % Callback function
        function DefaultLocationCheckBoxValueChanged(app, event)
            value = app.DefaultLocationCheckBox.Value;
            verybig(app);
            patternAzimuth(d,app.fq);
        end

        % Value changed function: TransmitterWSNLatitudeEditField
        function TransmitterWSNLatitudeEditFieldValueChanged(app, event)
            value = app.TransmitterWSNLatitudeEditField.Value;
            app.transmitterLat = value;
            
        end

        % Value changed function: TransmitterWSNLongitudeEditField
        function TransmitterWSNLongitudeEditFieldValueChanged(app, event)
            value = app.TransmitterWSNLongitudeEditField.Value;
            app.transmitterLon = value;
            %Latitude = Latitvalue * 0,;
        end

        % Value changed function: RecieverHUBLatitudeEditField
        function RecieverHUBLatitudeEditFieldValueChanged(app, event)
            value = app.RecieverHUBLatitudeEditField.Value;
            app.recieverLat = value;
        end

        % Value changed function: RecieverHUBLongitudeEditField
        function RecieverHUBLongitudeEditFieldValueChanged(app, event)
            value = app.RecieverHUBLongitudeEditField.Value;
            app.recieverLon = value;
            
        end

        % Value changed function: 
        % MoveWSNfromdefaultinYDirectionby1mSpinner_2
        function MoveWSNfromdefaultinYDirectionby1mSpinner_2ValueChanged(app, event)
            value = app.MoveWSNfromdefaultinYDirectionby1mSpinner_2.Value;
            app.dInMeters = value;
            app.latChange = ((1 ./ 111111) .* app.dInMeters);


            app.transmitterLat = app.transmitterLat + app.latChange;
           
            app.EquivalentLatChangeEditField.Value = app.latChange;


        end

        % Value changed function: 
        % MoveWSNfromdefaultinXDirectionby1mSpinner
        function MoveWSNfromdefaultinXDirectionby1mSpinnerValueChanged(app, event)
            value = app.MoveWSNfromdefaultinXDirectionby1mSpinner.Value;
            app.dInMeters = value;
            app.lonChange = ((1 ./ 111111) .* app.dInMeters);
       
            app.transmitterLon = app.transmitterLon + app.lonChange;
            app.EquivalentLonChangeEditField.Value = app.lonChange;
            
        end

        % Selection changed function: AntennaDiagramsHUBButtonGroup
        function AntennaDiagramsHUBButtonGroupSelectionChanged(app, event)
            selectedButton = app.AntennaDiagramsHUBButtonGroup.SelectedObject;
            if selectedButton == app.AzimuthButton
                app.Hub.patternAzimuth(app.fq);
            end
            if selectedButton == app.ElevationButton
               app.Hub.patternElevation(app.fq);
            end
            if selectedButton == app.DButton
                app.Hub.pattern(app.fq);
            end
           if selectedButton == app.ModellButton_2
               show(app.Hub);
           end
                
        end

        % Selection changed function: AntennaDiagramsX1ButtonGroup
        function AntennaDiagramsX1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.AntennaDiagramsX1ButtonGroup.SelectedObject;
            if selectedButton == app.AzimuthButton_2
                app.Wsn.patternAzimuth(app.fq);
            end
            if selectedButton == app.ElevationButton_2
               app.Wsn.patternElevation(app.fq);
            end
            if selectedButton == app.DButton_2
                app.Wsn.pattern(app.fq);
            end
            if selectedButton == app.ModellButton
               show(app.Wsn);
           end
        end

        % Value changed function: ShowConnectionCheckBox_2
        function ShowConnectionCheckBox_2ValueChanged(app, event)
            value = app.ShowConnectionCheckBox_2.Value;
            txs = txsite('Name', app.transmitterName, 'Latitude', app.transmitterLat,'Longitude',app.transmitterLon, 'Antenna', app.Wsn,'TransmitterFrequency', app.fq,'TransmitterPower',0.063,'SystemLoss', 35);
            rxs = rxsite('Name', app.recieverName, 'Latitude', app.recieverLat, 'Longitude',app.recieverLon, 'Antenna', app.Hub,'ReceiverSensitivity', -101);
            show(txs);
            show(rxs);
            
            signal95 = -95;
            signal96 = -96;
            signal97 = -97;
            signal99 = -99;
            signal103 = -103;
            signal105 = -105;
            signal107 = -107;
            signal109 = -109;
           
            signal101 = -101;
            signal95Color = "red";
            signal97Color = "magenta";
            
            signal99Color ="black";
            signal101Color = "green";
            signal103Color= "yellow";
            
            signal105Color = "cyan";
            signal107Color = "blue";
            signal109Color = "white";
            signal96Color = "black";


            if app.showCoverage == 1
              coverage(txs,'SignalStrengths',[ signal95, signal96, signal97, signal99,signal101, signal103, signal105,signal107, signal109], 'Colors', [ signal95Color,signal96Color,  signal97Color,signal99Color,  signal101Color,signal103Color,signal105Color, signal107Color, signal109Color]);
            end
            
            link(rxs,txs);  
            if (link (rxs,txs) == 0)
                app.ConnectedLamp.Color = 'white';
                app.DisconnectedLamp.Color = 'red';
            elseif ( link (rxs, txs) == 1)
                    app.DisconnectedLamp.Color = 'white';
                    app.ConnectedLamp.Color = 'green';
                    app.lastconnectedlat = app.recieverLat;
                    app.lastconnectedlon = app.recieverLon;
                    app.lastconnecteddistance = distance(txs, rxs);
                    app.signalStrength = sigstrength(rxs, txs);
                    app.LastConnectedDistanceEditField.Value = num2str(app.lastconnecteddistance, 10);
                    app.LastConnectedLatitudeEditField.Value = num2str(app.lastconnectedlat, 10);
                    app.LastConnectedLongitudeEditField.Value = num2str(app.lastconnectedlon, 10);
                    app.SignalStrengthEditField.Value = num2str(app.signalStrength, 6);
            end
           
        end

        % Value changed function: ShowCoverageCheckBox
        function ShowCoverageCheckBoxValueChanged(app, event)
            value = app.ShowCoverageCheckBox.Value;
            app.showCoverage = 1;
        end

        % Callback function
        function TransmittingPowerWSNWSliderValueChanged(app, event)
            value = app.TransmittingPowerWSNWSlider.Value;
            app.transmissionPower = value.';
        end

        % Value changed function: USAntennaCheckBox
        function USAntennaCheckBoxValueChanged(app, event)
            value = app.USAntennaCheckBox.Value;
            app.fq = 915e6;
            
        end

        % Value changed function: EUAntennaCheckBox
        function EUAntennaCheckBoxValueChanged(app, event)
            value = app.EUAntennaCheckBox.Value;
            app.fq = 868e6;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AutoImageGenerationCheckBox = uicheckbox(app.UIFigure);
            app.AutoImageGenerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoImageGenerationCheckBoxValueChanged, true);
            app.AutoImageGenerationCheckBox.Text = 'Auto Image Generation';
            app.ShowConnectionCheckBox_2 = uicheckbox(app.UIFigure);
            app.ShowConnectionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowConnectionCheckBox_2ValueChanged, true);
            app.ShowConnectionCheckBox_2.Text = 'Show Connection';
            app.AntennaDiagramsHUBButtonGroup = uibuttongroup(app.UIFigure);
            app.AntennaDiagramsHUBButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AntennaDiagramsHUBButtonGroupSelectionChanged, true);
            app.AntennaDiagramsHUBButtonGroup.Title = 'Antenna Diagrams HUB';
            app.AzimuthButton = uitogglebutton(app.AntennaDiagramsHUBButtonGroup);
            app.AzimuthButton.Text = 'Azimuth';
            app.AzimuthButton.Value = true;
            app.ElevationButton = uitogglebutton(app.AntennaDiagramsHUBButtonGroup);
            app.ElevationButton.Text = 'Elevation';
            app.DButton = uitogglebutton(app.AntennaDiagramsHUBButtonGroup);
            app.DButton.Text = '3D';
            app.ModellButton_2 = uitogglebutton(app.AntennaDiagramsHUBButtonGroup);
            app.ModellButton_2.Text = 'Modell';
            app.AntennaDiagramsX1ButtonGroup = uibuttongroup(app.UIFigure);
            app.AntennaDiagramsX1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AntennaDiagramsX1ButtonGroupSelectionChanged, true);
            app.AntennaDiagramsX1ButtonGroup.Title = 'Antenna Diagrams X1';
            app.AzimuthButton_2 = uitogglebutton(app.AntennaDiagramsX1ButtonGroup);
            app.AzimuthButton_2.Text = 'Azimuth';
            app.AzimuthButton_2.Value = true;
            app.ElevationButton_2 = uitogglebutton(app.AntennaDiagramsX1ButtonGroup);
            app.ElevationButton_2.Text = 'Elevation';
            app.DButton_2 = uitogglebutton(app.AntennaDiagramsX1ButtonGroup);
            app.DButton_2.Text = '3D';
            app.ModellButton = uitogglebutton(app.AntennaDiagramsX1ButtonGroup);
            app.ModellButton.Text = 'Modell';
            app.ConnectedLampLabel = uilabel(app.UIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.LastConnectedLatitudeEditFieldLabel = uilabel(app.UIFigure);
            app.LastConnectedLatitudeEditFieldLabel.Text = {'Last Connected'; ' Latitude'};
            app.LastConnectedLatitudeEditField = uieditfield(app.UIFigure, 'text');
            app.EquivalentLatChangeLabel = uilabel(app.UIFigure);
            app.EquivalentLatChangeLabel.Text = {'Equivalent Lat '; 'Change'};
            app.EquivalentLatChangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EquivalentLatChangeEditField.ValueDisplayFormat = '%.7f';
            app.EquivalentLonChangeLabel = uilabel(app.UIFigure);
            app.EquivalentLonChangeLabel.Text = {'Equivalent Lon '; 'Change'};
            app.EquivalentLonChangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EquivalentLonChangeEditField.ValueDisplayFormat = '%.7f';
            app.LastConnectedDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.LastConnectedDistanceEditFieldLabel.Text = {'Last Connected'; 'Distance'};
            app.LastConnectedDistanceEditField = uieditfield(app.UIFigure, 'text');
            app.LastConnectedLongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.LastConnectedLongitudeEditFieldLabel.Text = {'Last Connected'; 'Longitude'};
            app.LastConnectedLongitudeEditField = uieditfield(app.UIFigure, 'text');
            app.DisconnectedLampLabel = uilabel(app.UIFigure);
            app.DisconnectedLampLabel.Text = 'Disconnected';
            app.DisconnectedLamp = uilamp(app.UIFigure);
            app.TransmitterWSNLatitudeLabel = uilabel(app.UIFigure);
            app.TransmitterWSNLatitudeLabel.Text = {'Transmitter'; '(WSN)'; 'Latitude'; ''};
            app.TransmitterWSNLatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransmitterWSNLatitudeEditField.ValueDisplayFormat = '%.6f';
            app.TransmitterWSNLatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @TransmitterWSNLatitudeEditFieldValueChanged, true);
            app.TransmitterWSNLatitudeEditField.Value = 52.474817;
            app.TransmitterWSNLongitudeLabel = uilabel(app.UIFigure);
            app.TransmitterWSNLongitudeLabel.Text = {'Transmitter'; '(WSN)'; 'Longitude'};
            app.TransmitterWSNLongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransmitterWSNLongitudeEditField.ValueDisplayFormat = '%.6f';
            app.TransmitterWSNLongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @TransmitterWSNLongitudeEditFieldValueChanged, true);
            app.TransmitterWSNLongitudeEditField.Value = 13.399024;
            app.RecieverHUBLatitudeEditFieldLabel = uilabel(app.UIFigure);
            app.RecieverHUBLatitudeEditFieldLabel.Text = {'Reciever'; '(HUB)'; 'Latitude'; ''};
            app.RecieverHUBLatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecieverHUBLatitudeEditField.ValueDisplayFormat = '%.6f';
            app.RecieverHUBLatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @RecieverHUBLatitudeEditFieldValueChanged, true);
            app.RecieverHUBLatitudeEditField.Value = 52.475087;
            app.RecieverHUBLongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.RecieverHUBLongitudeEditFieldLabel.Text = {'Reciever'; '(HUB)'; ' Longitude'};
            app.RecieverHUBLongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecieverHUBLongitudeEditField.ValueDisplayFormat = '%.6f';
            app.RecieverHUBLongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @RecieverHUBLongitudeEditFieldValueChanged, true);
            app.RecieverHUBLongitudeEditField.Value = 13.405266;
            app.MoveWSNfromdefaultinYDirectionby1mSpinner_2Label = uilabel(app.UIFigure);
            app.MoveWSNfromdefaultinYDirectionby1mSpinner_2Label.Text = {'Move WSN'; 'from default in'; ' Y-Direction by 1 m'};
            app.MoveWSNfromdefaultinYDirectionby1mSpinner_2 = uispinner(app.UIFigure);
            app.MoveWSNfromdefaultinYDirectionby1mSpinner_2.Step = 10;
            app.MoveWSNfromdefaultinYDirectionby1mSpinner_2.Limits = [-1000 1000];
            app.MoveWSNfromdefaultinYDirectionby1mSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MoveWSNfromdefaultinYDirectionby1mSpinner_2ValueChanged, true);
            app.MoveWSNfromdefaultinXDirectionby1mSpinnerLabel = uilabel(app.UIFigure);
            app.MoveWSNfromdefaultinXDirectionby1mSpinnerLabel.Text = {'Move WSN'; ' from default in'; ' X-Direction by 1 m'};
            app.MoveWSNfromdefaultinXDirectionby1mSpinner = uispinner(app.UIFigure);
            app.MoveWSNfromdefaultinXDirectionby1mSpinner.Step = 10;
            app.MoveWSNfromdefaultinXDirectionby1mSpinner.Limits = [-1000 1000];
            app.MoveWSNfromdefaultinXDirectionby1mSpinner.ValueChangedFcn = createCallbackFcn(app, @MoveWSNfromdefaultinXDirectionby1mSpinnerValueChanged, true);
            app.SignalStrengthEditFieldLabel = uilabel(app.UIFigure);
            app.SignalStrengthEditFieldLabel.Text = 'Signal Strength';
            app.SignalStrengthEditField = uieditfield(app.UIFigure, 'text');
            app.ShowCoverageCheckBox = uicheckbox(app.UIFigure);
            app.ShowCoverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCoverageCheckBoxValueChanged, true);
            app.ShowCoverageCheckBox.Text = 'Show Coverage';
            app.USAntennaCheckBox = uicheckbox(app.UIFigure);
            app.USAntennaCheckBox.ValueChangedFcn = createCallbackFcn(app, @USAntennaCheckBoxValueChanged, true);
            app.USAntennaCheckBox.Text = 'US Antenna';
            app.EUAntennaCheckBox = uicheckbox(app.UIFigure);
            app.EUAntennaCheckBox.ValueChangedFcn = createCallbackFcn(app, @EUAntennaCheckBoxValueChanged, true);
            app.EUAntennaCheckBox.Text = 'EU Antenna';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = antenna

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end