classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SingleCurveAnalysisTab          matlab.ui.container.Tab
        LoadDataButton                  matlab.ui.control.Button
        StartfromthebeginningButton     matlab.ui.control.StateButton
        ExperimentNumberSpinnerLabel    matlab.ui.control.Label
        ExperimentNumberSpinner         matlab.ui.control.Spinner
        SmoothnessSpinnerLabel          matlab.ui.control.Label
        SmoothnessSpinner               matlab.ui.control.Spinner
        InformationtobeshownonthegraphLabel  matlab.ui.control.Label
        FirstOrderDerivativeCheckBox    matlab.ui.control.CheckBox
        OnlySmoothingCheckBox           matlab.ui.control.CheckBox
        CompareCheckBox                 matlab.ui.control.CheckBox
        DrawLinesonGraphCheckBox        matlab.ui.control.CheckBox
        AnalysisofBigDataTab            matlab.ui.container.Tab
        LoadDataforAnalysisButton       matlab.ui.control.Button
        PlotDataNumberLabel             matlab.ui.control.Label
        PlotDataNumberSpinner           matlab.ui.control.Spinner
        ExperimentNumberSpinner_2Label  matlab.ui.control.Label
        ExperimentNumberSpinner_2       matlab.ui.control.Spinner
        PlotWholeCurveCheckBox          matlab.ui.control.CheckBox
        SmoothingLabel                  matlab.ui.control.Label
        SmoothingSpinner                matlab.ui.control.Spinner
        ThisExportstheResultofCurrentExperimenttoExcelLabel  matlab.ui.control.Label
        BaselineCheckBox                matlab.ui.control.CheckBox
        ClearFigureCheckBox             matlab.ui.control.CheckBox
        IStableCheckBox_2               matlab.ui.control.CheckBox
        I90CheckBox                     matlab.ui.control.CheckBox
        T90CheckBox                     matlab.ui.control.CheckBox
        F10CheckBox                     matlab.ui.control.CheckBox
        SensitivityCheckBox             matlab.ui.control.CheckBox
        ExportResulttoExcelButton       matlab.ui.control.Button
        AutoExportCheckBox              matlab.ui.control.CheckBox
        CheckthisboxandChangeExperimentnumbertoexportautomaticallyLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %%%%% Data for  Single Curve Analysis %%%%%%%%%%%%%
        DataToBePloted;  %store data to be plotteed the original excel sheet
        BaseLine;        % baseline
        MaxValue;        %maxvalue
        T90;
        F90;
        IStable;
        I90;
        DiffSmooth;      %First derivate of the function
        MaxDiff;         %maximum Differential
        MinDiff,         %minimum Differential
        MaxDiffIndex;    %index of Max Differential
        MinDiffIndex;    %index of Min Differential
        Rising;
        DataCheck;
        
        %%%% Data for Single Curve Analysis %%%%%%%%%%%%%%%
        
        %%%%% Data For Big Data Analysis%%%%%%%%%%
        
        
        DataForAnalysis;
        NumbersofExperimentOnEachConcentrationValue ;  % Indexes of Data
        SegmentedData;
        BaseLineVector;
        I90Vector;
        IStableVector;
        T90Vector;
        F90Vector;
        TransformedData;
        HelperForTemperature;
        Sensitivity;


        %%%%% Data for Big Data Analysis%%%%%%%%%%%%


    end
    
    methods (Access = private)
        
        function Amedian =  DataSmoothing(app)  %Function for data smoothing, takes the column number and smooths the data,
            
            
            YData = fix(app.ExperimentNumberSpinner.Value+1);
            
            y = app.DataToBePloted.(YData);
            
            window = app.SmoothnessSpinner.Value;
            Amedian = movmedian(rmmissing(y), window);
            
            
        end
        
        function RisingOrFalling(app)
            if(app.MaxDiffIndex <app.MinDiffIndex)
                app.Rising = 1;
                
            else
                app.Rising = 0;
            end
        end
        
        function  StandardDeviationOfMinAndMaxDataToCheckDataQuality(app)
            
            MaxDiffIndexVector = [];
            MinDiffIndexVector = [];
            
            for u = 1:40
                
                DataIndex = app.ExperimentNumberSpinner.Value +1;
                DataHere = app.DataToBePloted.(DataIndex);
                DataForStd = movmedian(rmmissing(DataHere), u);
                Diff = diff(DataForStd);
                
                
                MaxDiffIndexStd = find(max(Diff) == Diff, 1);
                MinDiffIndexStd = find(min(Diff) == Diff, 1);
                
                MaxDiffIndexVector(end+1) = MaxDiffIndexStd;
                MinDiffIndexVector(end+1) = MinDiffIndexStd;


            end
            
            DeviationMax = std(MaxDiffIndexVector);
            DeviationMin = std(MinDiffIndexVector);
            
            
            if(DeviationMax < 30 | DeviationMin < 30 )
                
                app.DataCheck = 1;
            else
                app.DataCheck = 0;
                
            end
            
            
        end
        
        function [BaseLineBegin, BaseLineEnd] = BaseLineCalculator(app,DataForBaseLine, PositionOfMaxOrMin)
            NumberToBeSubtracted = mod(PositionOfMaxOrMin , 3);  %number to be  subtracted for diviisble by 3
            BaseLineEndPoint = PositionOfMaxOrMin-NumberToBeSubtracted;
            BaseLineData = DataForBaseLine(1: BaseLineEndPoint);
            BaseLineData = reshape(BaseLineData, [], 3);
            StdofBaseLineData = std(BaseLineData);
            MaxStdPosition = find(StdofBaseLineData == min(StdofBaseLineData),1); % ; 1 ensures that only one argument is evaluated
            
            if(MaxStdPosition == 1)  %no need to look for the case if there is no difference in Std position because of the above statement
                BaseLineBegin = 1;
                BaseLineEnd = round(BaseLineEndPoint /3);
            elseif (MaxStdPosition ==2 )
                BaseLineBegin = round(BaseLineEndPoint /3);
                BaseLineEnd = round(BaseLineEndPoint /2);
                
            elseif (MaxStdPosition ==3 )
                BaseLineBegin = round(BaseLineEndPoint /2);
                BaseLineEnd = BaseLineEndPoint;
            end


        end
        
        
        function Calculator(app)
            
            NewData = app.DataSmoothing();
            app.DiffSmooth = diff(NewData);
            app.MaxDiff = max(app.DiffSmooth);
            app.MinDiff = min(app.DiffSmooth);
            app.MaxDiffIndex = find(app.DiffSmooth == app.MaxDiff,1);  %only the first occurence
            app.MinDiffIndex = find(app.DiffSmooth == app.MinDiff,1);  % only the first occurence
            
            app.StandardDeviationOfMinAndMaxDataToCheckDataQuality();
            
            if(app.MinDiffIndex - 30 > 0 & app.MaxDiffIndex - 30 > 0)
                
                
                if(app.DataCheck == 1)
                    
                    app.RisingOrFalling();
                    
                    if(app.Rising == 1)
                        
                        [Limit1, Limit2] = app.BaseLineCalculator(NewData, app.MaxDiffIndex);
                        app.BaseLine = mean(NewData(Limit1:Limit2));
                        app.IStable = mode(NewData((app.MinDiffIndex -30):app.MinDiffIndex));
                        app.I90 = 0.9*(app.IStable(1) -app.BaseLine) + (app.BaseLine);
                        I10 = 0.1*(app.IStable(1) -app.BaseLine) + (app.BaseLine);
                        
                        
                    else
                        [Limit1, Limit2] = app.BaseLineCalculator(NewData, app.MinDiffIndex);
                        app.BaseLine = mean(NewData(Limit1:Limit2));
                        app.IStable = mean(NewData((app.MaxDiffIndex -30):(app.MaxDiffIndex-5))); %30 and 5 randomly choosen to assume the totzeit
                        app.I90 = 0.9*(app.IStable(1)-app.BaseLine) + app.BaseLine;
                        I10 = 0.1*(app.IStable(1) -app.BaseLine) + (app.BaseLine);
                        
                        
                    end
                    PreDataT90 = NewData - app.I90;
                    PreDataF90 = NewData - I10;  % not on global scale because I 90 need not be drawn
                    app.T90 = find(diff(sign(PreDataT90)));
                    app.F90 = find(diff(sign(PreDataF90)));
                    app.MaxValue = max(NewData);
                    
                else
                    msgbox({'Data Cannot Be Analysed';'Try Changing Smoothing'}, 'Error', 'error');
                end
            else
                msgbox({'Data Cannot Be Analysed';'Try Changing Smoothing'}, 'Error', 'error');
            end
            
        end


        function DrawLines(app)
            
            
            app.Calculator();
            
            if((app.DataCheck == 1))
                hold on
                yline(app.IStable, '--r', "IStable = "+ num2str(app.IStable(1)-app.BaseLine) +" A", 'LabelHorizontalAlignment','left')
                
                yline(app.BaseLine, '--m', "BaseLine = "+num2str(app.BaseLine)+" A",'LabelHorizontalAlignment','center')
                yline(app.I90, '-.r', "I90 = " +num2str(app.I90 -app.BaseLine) +" A", 'LabelHorizontalAlignment','right')
                if(app.Rising == 1)
                    xline(app.T90(1), ':k', "T90 = " +num2str(app.T90(1)-app.MaxDiffIndex)+" s", 'LabelVerticalAlignment','bottom')
                    xline(app.MaxDiffIndex, ':k') %, "Probable Start = " +num2str(app.MaxDiffIndex)+" s", 'LabelVerticalAlignment','bottom'%
                    xline(app.F90(end) , ':k', "F90 = " +num2str(app.F90(end)-app.MinDiffIndex)+" s", 'LabelVerticalAlignment','bottom')
                    xline(app.MinDiffIndex, ':k')%, "Probable End = " +num2str(app.MinDiffIndex)+" s", 'LabelVerticalAlignment','bottom')
                else
                    xline(app.T90(1), ':k', "T90 = " +num2str(app.T90(1)+app.MinDiffIndex)+" s", 'LabelVerticalAlignment','bottom')
                    xline(app.MaxDiffIndex, ':k')
                    xline(app.F90(end), ':k', "F90 = " +num2str(app.F90(end)-app.MaxDiffIndex)+" s", 'LabelVerticalAlignment','bottom')
                    xline(app.MinDiffIndex, ':k')
                end
                
            end
            
            
        end
        
        
        function DrawDiff(app)
            hold on
            plot(app.DiffSmooth);
        end
        
        
        function PlotData(app)
            YData = app.ExperimentNumberSpinner.Value+1;
            x= app.DataToBePloted.(1);
            y= app.DataToBePloted.(YData);
            
            if(app.OnlySmoothingCheckBox.Value ==1)
                
                Amedian = app.DataSmoothing();
                clf;
                plot( Amedian, 'b');
                xlabel('time     (s)');
                ylabel('current    (nA)');


            end
            
            if(app.CompareCheckBox.Value ==1)
                
                Amedian = app.DataSmoothing();
                clf;
                plot( Amedian , 'b');
                hold on
                plot(x, y, 'r');
                xlabel('time     (s)');
                ylabel('current    (nA)');
                
                
            end
            
            
        end
        
        function SegmentDataB(app )
            app.TransformedData = 0;  % to ensure everytime the Dat is cleared when the function is called. Result first element is 0 ;
            app.NumbersofExperimentOnEachConcentrationValue = find(diff(app.DataForAnalysis.(6))~= 0); % 6 becasue 6th column has the infomation about where the data is located
            WorkingData = app.DataForAnalysis.(app.ExperimentNumberSpinner_2.Value+7); %begins at 8 in data set
            
            TransposedBegasung = app.DataForAnalysis.(6).';
            IndicesForExperiment = find(diff(rmmissing(TransposedBegasung)))+1;
            
            
            BegasungBeginn = IndicesForExperiment(1:2:end) -60; % 60 seconds before experiment begins
            BegasungEnd = IndicesForExperiment(2:2:end) +180 ; % 60 seconds after experiment ends
            
            app.HelperForTemperature = BegasungBeginn;


            for i = 1: size(BegasungBeginn, 2)
                
                if(i ==  size(BegasungBeginn, 2))
                    BegasungBeginn = IndicesForExperiment(1:2:end) -60; % 60 seconds before experiment begins
                    BegasungEnd = IndicesForExperiment(2:2:end) +60 ; % 60 seconds after experiment ends
                    x = WorkingData(BegasungBeginn(i): (BegasungEnd(i))); % the last experiment does not have 120 seconds
                    app.TransformedData = [app.TransformedData, {x}];
                    
                else
                    x = WorkingData(BegasungBeginn(i): BegasungEnd(i));
                    app.TransformedData = [app.TransformedData, {x}];
                end
                
            end
            app.TransformedData(1) = [];  %remove the first 0 element from array


        end
        
        function TemperatureData = Temperaturerange(app)
            
            TemperatureData = app.DataForAnalysis.(7); % BecauseTemperature Data is on the 7th Row
            TemperatureData = TemperatureData(app.HelperForTemperature);
            
            
        end
        
        
        function  CalculatorB (app)
            app.IStableVector = 0;
            app.BaseLineVector = 0;
            app.I90Vector = 0;
            app.T90Vector = 0;
            app.F90Vector = 0;
            ConcentrationValueHelper_= app.DataForAnalysis.Properties.VariableNames;
            ConcentrationValueHelper_ = ConcentrationValueHelper_{app.ExperimentNumberSpinner_2.Value+7}; %experiment begins at 8
            ConcentrationValue = strip(ConcentrationValueHelper_, 'left', 'x');
            ConcentrationValue = str2double(ConcentrationValue);


            for c = 1: 1: size(app.TransformedData,2)
                
                DataForLoop_ = cell2mat(app.TransformedData(1, c)); % Becasue the dat is in cell array format
                DataForLoop_ = movmedian(rmmissing(DataForLoop_),app.SmoothingSpinner.Value);
                DataQuality_ = app.StandardDeviationOfMinAndMaxDataToCheckDataQualityB(DataForLoop_);
                
                
                [MaxDiffIndex_, MinDiffIndex_] = app.DerivativeCalculatorB(DataForLoop_);
                
                if(MaxDiffIndex_ < MinDiffIndex_)
                    Rising_ = 1;
                else
                    Rising_ = 0;
                end
                
                
                if(DataQuality_ == 1 )
                    
                    if(Rising_ == 1 )
                        [Limit1Base_, Limit2Base_] = app.BaseLineCalculator(DataForLoop_, MaxDiffIndex_);
                        app.BaseLineVector(end+1) =  mean(DataForLoop_(Limit1Base_: Limit2Base_));
                        app.IStableVector(end +1) =  mode(DataForLoop_((MinDiffIndex_ - 30):MinDiffIndex_));
                      
                        app.I90Vector(end+1) = 0.9* (app.IStableVector(end)-app.BaseLineVector(end));% -app.BaseLineVector(end)) + (app.BaseLineVector(end));


                    else
                        [Limit1Base_, Limit2Base_] = app.BaseLineCalculator(DataForLoop_, MinDiffIndex_);
                        app.BaseLineVector(end+1) =  mean(DataForLoop_(Limit1Base_: Limit2Base_));
                        app.IStableVector(end +1) =  mode(DataForLoop_((MaxDiffIndex_ -30):MaxDiffIndex_)); %-30  chosen randomly
                       
                        app.I90Vector(end+1) = 0.9 * (app.IStableVector(end)-app.BaseLineVector(end)) ;% + (app.BaseLineVector(end));


                    end
                    PreDataT90 = DataForLoop_ - 0.9 * app.IStableVector(end); %because  the graph does not start from zero. meaning you subtract90%of istable which does not start form zero 
                    ElemetsFound_t = find(diff(sign(PreDataT90))); 
                    app.T90Vector(end+1) = ElemetsFound_t(1);
                    
                    I10 = 0.1 *(app.IStableVector(end) ) + (app.BaseLineVector(end));
                    
                    PreDataF90 = DataForLoop_ - I10;
                    ElementsFound_ = find(diff(sign(PreDataF90)));
                    app.F90Vector(end+1) = ElementsFound_(end);


                    if (Rising_== 1)
                        app.T90Vector(end) = app.T90Vector(end)- MaxDiffIndex_;
                        app.F90Vector(end) = app.F90Vector(end) - MinDiffIndex_;
                    else
                        app.T90Vector(end) = app.T90Vector(end)-MinDiffIndex_;
                        app.F90Vector(end) = app.F90Vector(end) -MaxDiffIndex_;
                        
                    end


                end
                
                
            end
            app.IStableVector(1) = [];   %% first element is 0 because of the loop tot balance that
            app.Sensitivity = app.IStableVector /ConcentrationValue;
            app.BaseLineVector(1) = [];
            app.I90Vector (1) = [];
            app.T90Vector (1) = [];
           
            app.F90Vector (1)  = [];
            
        end
        
        function  DataQuality =   StandardDeviationOfMinAndMaxDataToCheckDataQualityB(app, Data )
            
            MaxDiffIndexVector = [];
            MinDiffIndexVector = [];
            
            for u = 1:40
                
                
                DataHere = Data;
                Smoothness = app.SmoothingSpinner.Value;
                DataForStd = movmedian(rmmissing(DataHere), Smoothness );
                Diff = diff(DataForStd);
                
                
                MaxDiffIndexStd = find(max(Diff) == Diff, 1);
                MinDiffIndexStd = find(min(Diff) == Diff, 1);
                
                MaxDiffIndexVector(end+1) = MaxDiffIndexStd;
                MinDiffIndexVector(end+1) = MinDiffIndexStd;


            end
            
            DeviationMax = std(MaxDiffIndexVector);
            DeviationMin = std(MinDiffIndexVector);
            
            
            if(DeviationMax < 30 | DeviationMin < 30 )
                
                DataQuality = 1;
            else
                DataQuality = 0;
                
            end
            
            
        end


        function [MaxDiffIndexForOneCurve, MinDiffIndexForOneCurve] = DerivativeCalculatorB(app, Data)
            
           	DataForDifferential =  Data;
           	Derrivative = diff(DataForDifferential);
           	MaxDiff_ = max(Derrivative);
           	MinDiff_ = min(Derrivative);
           	MaxDiffIndexForOneCurve = find(MaxDiff_ == Derrivative, 1);
           	MinDiffIndexForOneCurve = find(MinDiff_ == Derrivative, 1);
        end


        function DrawWholeCurveB(app)
            DataNumber = app.ExperimentNumberSpinner_2.Value+7;  % Data Begins at Index 8
            plot(app.DataForAnalysis.(DataNumber));
            
        end
        
        function PlotBaseLineB(app)
            
            BaseData= app.BaseLineVector;
            XCoordinate = app.Temperaturerange();  %Uncomment this to use
            % temperature range otherwise, the experiments will be shown in
            % the order they appear in the excel file
            p =  size(app.HelperForTemperature,2);
            %XCoordinate= 1:p;
            
            
            scatter(XCoordinate, BaseData);
            xlabel('temperature (C)');
            ylabel('current    (nA)');
            %             Text = app.BaseLineVector.';
            %             Text = num2str(Text);
            %             Text = cellstr(Text);
            %             DXcordinate = 0.1;  %% so that the values  not overlap
            %             DBaseData = 0.1;  %% same as above
            %             text(XCoordinate +DXcordinate, BaseData+DBaseData, Text);
            
        end
        function PlotSensitiviy(app)
            
            BaseData= app.Sensitivity;
            XCoordinate = app.Temperaturerange();  %Uncomment this to use
            % temperature range otherwise, the experiments will be shown in
            % the order they appear in the excel file
            p =  size(app.HelperForTemperature,2);
            %XCoordinate= 1:p;
            
            
            scatter(XCoordinate, BaseData);
            xlabel('temperature     (C)');
            ylabel('Sensitivity   (IStable/Concentration)');
            
            
            %             Text = app.BaseLineVector.';
            %             Text = num2str(Text);
            %             Text = cellstr(Text);
            %             DXcordinate = 0.1;  %% so that the values  not overlap
            %             DBaseData = 0.1;  %% same as above
            %             text(XCoordinate +DXcordinate, BaseData+DBaseData, Text);
            
        end
        
        
        function PlotIStableB(app)
            
            hold on;
            IStableData= app.IStableVector;
            XCoordinate = app.Temperaturerange(); % Uncomment this to use
            % temperature range otherwise, the experiments will be shown in
            % the order they appear in the excel file
            p =  size(app.HelperForTemperature,2);
            % XCoordinate= 1:p;
            
            scatter(XCoordinate, IStableData);
            xlabel('temperature      (C)');
            ylabel('current    (nA)');
            %                 uncomment following block for scatter plot
            %                scatter(XCoordinate, IStableData);  % to draw scatter diagram
            %             Text = app.IStableVector.';
            %             Text = num2str(Text);
            %             Text = cellstr(Text);
            %             DXcordinate = 0.1;  %% so that the values  not overlap
            %             DBaseData = 0.1;  %% same as above
            %             text(XCoordinate +DXcordinate, IStableData+DBaseData, Text);
            
        end
        
         function PlotI90B(app)
            
            hold on;
            IStableData= app.I90Vector;
            XCoordinate = app.Temperaturerange(); % Uncomment this to use


            scatter(XCoordinate, IStableData);
            xlabel('temperature      (C)');
            ylabel('current    (nA)');
          
            
        end
        function PlotT90B(app)
            
            hold on;
            IStableData= app.T90Vector;
            XCoordinate = app.Temperaturerange(); % Uncomment this to use


            scatter(XCoordinate, IStableData);
            xlabel('temperature      (C)');
            ylabel('Time (s)');
          
            
        end
        function PlotF90B(app)
            
            hold on;
            IStableData= app.F90Vector;
            XCoordinate = app.Temperaturerange(); % Uncomment this to use


            scatter(XCoordinate, IStableData);
            xlabel('temperature      (C)');
            ylabel('Time (s)');
          
            
        end
        function DataGenerator(app)
            app.SegmentDataB();
            app.CalculatorB();
            BaseLine_A = app.BaseLineVector.';
            IStable_A = app.IStableVector.';
            Sensitivity_ = app.Sensitivity.';
            I90_A = app.I90Vector.';
            T90_S = app.T90Vector.';
            
            F90_S = app.F90Vector.';
            Temperature_C = app.Temperaturerange(); 
           
            
            T = table(Temperature_C ,BaseLine_A, IStable_A, Sensitivity_, I90_A, T90_S, F90_S); 
            
            p = "ResultsOfExperiment" +num2str(app.ExperimentNumberSpinner_2.Value)+".xls"; 
           
            writetable (T,p, 'UseExcel',true);
            end
        function  ThisFunctionCanDoEverything (app, DataForLoop_)
            app.IStable = 0;
            app.BaseLine = 0;
            app.I90 = 0;
            app.T90 = 0;
            app.F90 = 0;
            
            
            DataQuality_ = app.StandardDeviationOfMinAndMaxDataToCheckDataQualityB(DataForLoop_);
            DataForLoop_ = movmedian(rmmissing(DataForLoop_),app.SmoothingSpinner.Value);
            [MaxDiffIndex_, MinDiffIndex_] = app.DerivativeCalculatorB(DataForLoop_);
            
            if(MaxDiffIndex_ < MinDiffIndex_)
                Rising_ = 1;
            else
                Rising_ = 0;
            end
            
            
            if(DataQuality_ == 1 )
                
                if(Rising_ == 1 )
                    [Limit1, Limit2] = app.BaseLineCalculator(DataForLoop_, MaxDiffIndex_);
                    app.BaseLine = mean(DataForLoop_(Limit1:Limit2));
                    app.IStable = mode(DataForLoop_((MinDiffIndex_ -30):MinDiffIndex_));
                    app.I90 = 0.9*(app.IStable(1))  ;
                    
                else
                    [Limit1, Limit2] = app.BaseLineCalculator(DataForLoop_, MinDiffIndex_);
                    app.BaseLine = mean(DataForLoop_(Limit1:Limit2));
                    app.IStable = mode(DataForLoop_((MaxDiffIndex_ -30):(MaxDiffIndex_))); %-30  randomly choosen to assume the totzeit
                    app.I90 = 0.9*(app.IStable(1));
                    
                end
                PreDataT90 = DataForLoop_ - app.I90;
                I10 = 0.1*(app.IStable(1))  + app.BaseLine;
                PreDataF90 = DataForLoop_ - I10;  % not on global scale because I 90 need not be drawn
                app.T90 = find(diff(sign(PreDataT90)));
                app.F90 = find(diff(sign(PreDataF90)));
                app.MaxValue = max(DataForLoop_);
                
            end
            hold on
            yline(app.IStable, '--r', "IStable = "+ num2str(app.IStable(1)) +" A", 'LabelHorizontalAlignment','left')
           
            
            yline(app.BaseLine, '--m', "BaseLine = "+num2str(app.BaseLine)+" A",'LabelHorizontalAlignment','center')
            yline(app.I90, '-.r', "I90 = " +num2str(0.9*(app.IStable(1)-app.BaseLine) ) +" A", 'LabelHorizontalAlignment','right')
             yline(I10, '-.r', "I10 = " +num2str(I10 ) +" A", 'LabelHorizontalAlignment','right')
            if(Rising_ == 1)
                xline(app.T90(1), ':k', "T90 = " +num2str(app.T90(1)-MaxDiffIndex_)+" s", 'LabelVerticalAlignment','bottom')
                xline(MaxDiffIndex_, ':b') 
                xline(app.F90(end) , ':k', "F90 = " +num2str(app.F90(end)-MinDiffIndex_)+" s", 'LabelVerticalAlignment','bottom')
                xline(MinDiffIndex_, ':b')
            else
                xline(app.T90(1), ':k', "T90 = " +num2str(app.T90(1)-MinDiffIndex_)+" s", 'LabelVerticalAlignment','bottom')
                xline(MaxDiffIndex_, ':b'); 
                xline(app.F90(end), ':k', "F90 = " +num2str(app.F90(end)-MaxDiffIndex_)+" s", 'LabelVerticalAlignment','bottom')
                xline(MinDiffIndex_, ':b')
            end


        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.xlsx'; '*.xls'; '*.*'};
            [file, path] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
                fname = [path file];
                ImportOptions_ = detectImportOptions(fname, 'NumHeaderLines', 6);
                app.DataToBePloted = readtable(fname, ImportOptions_);
                
            end
        end

        % Callback function
        function ShowImageWithSmoothingCheckBoxValueChanged(app, event)
            value = app.ShowImageWithSmoothingCheckBox.Value;
            if (value ==1)
                app.PlotData();
                
            end
            
        end

        % Value changed function: OnlySmoothingCheckBox
        function OnlySmoothingCheckBoxValueChanged(app, event)
            value = app.OnlySmoothingCheckBox.Value;
            app.PlotData();
        end

        % Value changed function: SmoothnessSpinner
        function SmoothnessSpinnerValueChanged(app, event)
            value = app.SmoothnessSpinner.Value;
            app.PlotData();
            if(app.DrawLinesonGraphCheckBox.Value ==1)
                app.DrawLines();
                
            end
            if(app.FirstOrderDerivativeCheckBox.Value == 1)
                app.DrawDiff();
            end


        end

        % Value changed function: CompareCheckBox
        function CompareCheckBoxValueChanged(app, event)
            value = app.CompareCheckBox.Value;
            app.PlotData();
        end

        % Value changed function: ExperimentNumberSpinner
        function ExperimentNumberSpinnerValueChanged(app, event)
            value = app.ExperimentNumberSpinner.Value;
            app.PlotData();
            if(app.DrawLinesonGraphCheckBox.Value == 1)
                app.DrawLines();
                
            end
            if(app.FirstOrderDerivativeCheckBox.Value == 1)
                app.DrawDiff();
            end
            
            % app.DrawDerivate();
            % app.DrawSecondDerivate();
            
        end

        % Value changed function: DrawLinesonGraphCheckBox
        function DrawLinesonGraphCheckBoxValueChanged(app, event)
            value = app.DrawLinesonGraphCheckBox.Value;
            if(value == 1)
                app.DrawLines();
            end
            
        end

        % Value changed function: FirstOrderDerivativeCheckBox
        function FirstOrderDerivativeCheckBoxValueChanged(app, event)
            value = app.FirstOrderDerivativeCheckBox.Value;
            if(value ==1)
            app.DrawDiff();
            end
            
            
        end

        % Callback function
        function SecondOrderDerivateCheckBoxValueChanged(app, event)
            
            
        end

        % Callback function
        function SelectallCheckBoxValueChanged(app, event)
            value = app.SelectallCheckBox.Value;
            if (value ==1)
                app.DrawLines();
            end
            
        end

        % Callback function
        function GenerateDataAutomaticallyandsavetoafolderButtonPushed(app, event)


        end

        % Button pushed function: LoadDataforAnalysisButton
        function LoadDataforAnalysisButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.xlsx'; '*.xls'; '*.*'};
            [file, path] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
                fname = [path file];
                ImportOptions_ = detectImportOptions(fname, 'NumHeaderLines', 1);
                app.DataForAnalysis = readtable(fname, ImportOptions_);
                
            end
        end

        % Value changed function: PlotDataNumberSpinner
        function PlotDataNumberSpinnerValueChanged(app, event)
            value = app.PlotDataNumberSpinner.Value;
            app.SegmentDataB();
            
            if (value > size(app.TransformedData,2))
                msgbox({'Out Of Bounds ';'Check Data'}, 'Error', 'error');
            else
                DataToPlot = app.TransformedData(1, value);
                DataToPlot = cell2mat(DataToPlot);
                hold off;
                plot(DataToPlot);
                ylabel('Current (A)');
                xlabel('Time (s)');
                app.ThisFunctionCanDoEverything(DataToPlot);
            end


        end

        % Value changed function: ExperimentNumberSpinner_2
        function ExperimentNumberSpinner_2ValueChanged(app, event)
            value = app.ExperimentNumberSpinner_2.Value;
            
            if ( size(app.DataForAnalysis, 2)-7-value < 0 ) % 8 because data starts at 8
                msgbox({'Out Of Bounds ';'Check Data'}, 'Error', 'error');
                
            else
                app.SegmentDataB();
                
                hold off;
                %app.ThisFunctionCanDoEverything(DataToPlot);
                app.CalculatorB();
                if(app.BaselineCheckBox.Value == 1)
                    
                    
                    clf;
                    app.PlotBaseLineB();
                end
                if(app.IStableCheckBox_2.Value == 1 )
                    hold on ;
                    app.PlotIStableB();
                    
                end
                if(app.SensitivityCheckBox.Value == 1 )
                    hold on ;
                    app.PlotSensitiviy();
                    
                end
                if(app.I90CheckBox.Value == 1 )
                    hold on ;
                    app.PlotI90B();
                    
                end
                if(app.T90CheckBox.Value == 1 )
                    hold on ;
                    app.PlotT90B();
                    
                end
                if(app.F90CheckBox.Value == 1 )
                    hold on ;
                    app.PlotF90B();
                    
                end
                if(app.AutoExportCheckBox.Value == 1)
                app.DataGenerator(); 
            end
            end
            if(app.PlotWholeCurveCheckBox.Value == 1)
                app.DrawWholeCurveB();
                
            end


        end

        % Value changed function: PlotWholeCurveCheckBox
        function PlotWholeCurveCheckBoxValueChanged(app, event)
            value = app.PlotWholeCurveCheckBox.Value;
            if(value == 1  )
                app.DrawWholeCurveB();
            end
        end

        % Value changed function: BaselineCheckBox
        function BaselineCheckBoxValueChanged(app, event)
            value = app.BaselineCheckBox.Value;
            if(value == 1)
                clf;
                app.SegmentDataB();
                app.CalculatorB();
                
                app.PlotBaseLineB();


            end
        end

        % Value changed function: ClearFigureCheckBox
        function ClearFigureCheckBoxValueChanged(app, event)
            value = app.ClearFigureCheckBox.Value;
            if (value == 1)
                clf;
                hold on;
            end
        end

        % Value changed function: IStableCheckBox_2
        function IStableCheckBox_2ValueChanged(app, event)
            
            value = app.IStableCheckBox_2.Value;
            if(value == 1)
                clf;
                app.SegmentDataB();
                app.CalculatorB();
                
                app.PlotIStableB();


            end
        end

        % Value changed function: SensitivityCheckBox
        function SensitivityCheckBoxValueChanged(app, event)
            value = app.SensitivityCheckBox.Value;
            if(value == 1)
                clf;
                app.SegmentDataB();
                app.CalculatorB();
                
                app.PlotSensitiviy();


            end
            
        end

        % Value changed function: StartfromthebeginningButton
        function StartfromthebeginningButtonValueChanged(app, event)
            value = app.StartfromthebeginningButton.Value;
            app.ExperimentNumberSpinner.Value = 1;
        end

        % Callback function
        function SelectAllCheckBoxValueChanged(app, event)
           
        end

        % Value changed function: I90CheckBox
        function I90CheckBoxValueChanged(app, event)
            value = app.I90CheckBox.Value;
            if(value == 1)
                clf;
                app.SegmentDataB();
                app.CalculatorB();
                
                app.PlotI90B();


            end
        end

        % Value changed function: T90CheckBox
        function T90CheckBoxValueChanged(app, event)
            value = app.T90CheckBox.Value;
            if(value == 1)
                clf;
                app.SegmentDataB();
                app.CalculatorB();
                
                app.PlotT90B();


            end
        end

        % Value changed function: F10CheckBox
        function F10CheckBoxValueChanged(app, event)
            value = app.F90CheckBox.Value;
            if(value == 1)
                clf;
                app.SegmentDataB();
                app.CalculatorB();
                
                app.PlotF90B();


            end
        end

        % Button pushed function: ExportResulttoExcelButton
        function ExportResulttoExcelButtonPushed(app, event)
            
         app.DataGenerator(); 


        end

        % Value changed function: AutoExportCheckBox
        function AutoExportCheckBoxValueChanged(app, event)
            value = app.AutoExportCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleCurveAnalysisTab = uitab(app.TabGroup);
            app.SingleCurveAnalysisTab.Title = 'Single Curve Analysis';
            app.LoadDataButton = uibutton(app.SingleCurveAnalysisTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.StartfromthebeginningButton = uibutton(app.SingleCurveAnalysisTab, 'state');
            app.StartfromthebeginningButton.ValueChangedFcn = createCallbackFcn(app, @StartfromthebeginningButtonValueChanged, true);
            app.StartfromthebeginningButton.Text = 'Start from the beginning';
            app.ExperimentNumberSpinnerLabel = uilabel(app.SingleCurveAnalysisTab);
            app.ExperimentNumberSpinnerLabel.Text = {'Experiment'; 'Number'; ''};
            app.ExperimentNumberSpinner = uispinner(app.SingleCurveAnalysisTab);
            app.ExperimentNumberSpinner.Limits = [1 10];
            app.ExperimentNumberSpinner.RoundFractionalValues = 'on';
            app.ExperimentNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @ExperimentNumberSpinnerValueChanged, true);
            app.ExperimentNumberSpinner.Value = 1;
            app.SmoothnessSpinnerLabel = uilabel(app.SingleCurveAnalysisTab);
            app.SmoothnessSpinnerLabel.Text = 'Smoothness';
            app.SmoothnessSpinner = uispinner(app.SingleCurveAnalysisTab);
            app.SmoothnessSpinner.Limits = [1 40];
            app.SmoothnessSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothnessSpinnerValueChanged, true);
            app.SmoothnessSpinner.Value = 2;
            app.InformationtobeshownonthegraphLabel = uilabel(app.SingleCurveAnalysisTab);
            app.InformationtobeshownonthegraphLabel.Text = {'Information to be'; ' shown on the graph'};
            app.FirstOrderDerivativeCheckBox = uicheckbox(app.SingleCurveAnalysisTab);
            app.FirstOrderDerivativeCheckBox.ValueChangedFcn = createCallbackFcn(app, @FirstOrderDerivativeCheckBoxValueChanged, true);
            app.FirstOrderDerivativeCheckBox.Text = 'First Order Derivative';
            app.OnlySmoothingCheckBox = uicheckbox(app.SingleCurveAnalysisTab);
            app.OnlySmoothingCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnlySmoothingCheckBoxValueChanged, true);
            app.OnlySmoothingCheckBox.Text = 'Only Smoothing';
            app.CompareCheckBox = uicheckbox(app.SingleCurveAnalysisTab);
            app.CompareCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompareCheckBoxValueChanged, true);
            app.CompareCheckBox.Text = 'Compare';
            app.DrawLinesonGraphCheckBox = uicheckbox(app.SingleCurveAnalysisTab);
            app.DrawLinesonGraphCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawLinesonGraphCheckBoxValueChanged, true);
            app.DrawLinesonGraphCheckBox.Text = 'Draw  Lines on Graph';
            app.AnalysisofBigDataTab = uitab(app.TabGroup);
            app.AnalysisofBigDataTab.Title = 'Analysis of Big Data';
            app.LoadDataforAnalysisButton = uibutton(app.AnalysisofBigDataTab, 'push');
            app.LoadDataforAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataforAnalysisButtonPushed, true);
            app.LoadDataforAnalysisButton.Text = 'Load Data for Analysis';
            app.PlotDataNumberLabel = uilabel(app.AnalysisofBigDataTab);
            app.PlotDataNumberLabel.Text = {'Plot Data'; 'Number '};
            app.PlotDataNumberSpinner = uispinner(app.AnalysisofBigDataTab);
            app.PlotDataNumberSpinner.Limits = [0 100];
            app.PlotDataNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotDataNumberSpinnerValueChanged, true);
            app.ExperimentNumberSpinner_2Label = uilabel(app.AnalysisofBigDataTab);
            app.ExperimentNumberSpinner_2Label.Text = {'Experiment'; 'Number'};
            app.ExperimentNumberSpinner_2 = uispinner(app.AnalysisofBigDataTab);
            app.ExperimentNumberSpinner_2.Limits = [1 100];
            app.ExperimentNumberSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ExperimentNumberSpinner_2ValueChanged, true);
            app.ExperimentNumberSpinner_2.Value = 1;
            app.PlotWholeCurveCheckBox = uicheckbox(app.AnalysisofBigDataTab);
            app.PlotWholeCurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotWholeCurveCheckBoxValueChanged, true);
            app.PlotWholeCurveCheckBox.Text = 'Plot Whole Curve';
            app.SmoothingLabel = uilabel(app.AnalysisofBigDataTab);
            app.SmoothingLabel.Text = {'Smoothing '; ''};
            app.SmoothingSpinner = uispinner(app.AnalysisofBigDataTab);
            app.SmoothingSpinner.Limits = [1 40];
            app.SmoothingSpinner.Value = 1;
            app.ThisExportstheResultofCurrentExperimenttoExcelLabel = uilabel(app.AnalysisofBigDataTab);
            app.ThisExportstheResultofCurrentExperimenttoExcelLabel.Text = {'This Exports the '; 'Result of Current Experiment'; 'to Excel'; ''};
            app.BaselineCheckBox = uicheckbox(app.AnalysisofBigDataTab);
            app.BaselineCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineCheckBoxValueChanged, true);
            app.BaselineCheckBox.Text = 'Baseline';
            app.ClearFigureCheckBox = uicheckbox(app.AnalysisofBigDataTab);
            app.ClearFigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClearFigureCheckBoxValueChanged, true);
            app.ClearFigureCheckBox.Text = 'Clear Figure';
            app.IStableCheckBox_2 = uicheckbox(app.AnalysisofBigDataTab);
            app.IStableCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @IStableCheckBox_2ValueChanged, true);
            app.IStableCheckBox_2.Text = 'I Stable';
            app.I90CheckBox = uicheckbox(app.AnalysisofBigDataTab);
            app.I90CheckBox.ValueChangedFcn = createCallbackFcn(app, @I90CheckBoxValueChanged, true);
            app.I90CheckBox.Text = 'I90';
            app.T90CheckBox = uicheckbox(app.AnalysisofBigDataTab);
            app.T90CheckBox.ValueChangedFcn = createCallbackFcn(app, @T90CheckBoxValueChanged, true);
            app.T90CheckBox.Text = 'T90';
            app.F10CheckBox = uicheckbox(app.AnalysisofBigDataTab);
            app.F10CheckBox.ValueChangedFcn = createCallbackFcn(app, @F10CheckBoxValueChanged, true);
            app.F10CheckBox.Text = 'F10';
            app.SensitivityCheckBox = uicheckbox(app.AnalysisofBigDataTab);
            app.SensitivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensitivityCheckBoxValueChanged, true);
            app.SensitivityCheckBox.Text = 'Sensitivity';
            app.ExportResulttoExcelButton = uibutton(app.AnalysisofBigDataTab, 'push');
            app.ExportResulttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResulttoExcelButtonPushed, true);
            app.ExportResulttoExcelButton.Text = {'Export Result to '; 'Excel'};
            app.AutoExportCheckBox = uicheckbox(app.AnalysisofBigDataTab);
            app.AutoExportCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoExportCheckBoxValueChanged, true);
            app.AutoExportCheckBox.Text = 'Auto Export';
            app.CheckthisboxandChangeExperimentnumbertoexportautomaticallyLabel = uilabel(app.AnalysisofBigDataTab);
            app.CheckthisboxandChangeExperimentnumbertoexportautomaticallyLabel.Text = {'Check this box and Change'; 'Experiment number to '; 'export automatically'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end